classdef app11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NghimTab                        matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        Tab_1_PP                        matlab.ui.control.DropDown
        NhpphngtrnhLabel                matlab.ui.control.Label
        Tab_1_HamSo                     matlab.ui.control.EditField
        CnbEditFieldLabel               matlab.ui.control.Label
        Tab_1_b                         matlab.ui.control.NumericEditField
        CnaEditFieldLabel               matlab.ui.control.Label
        Tab_1_a                         matlab.ui.control.NumericEditField
        NhpkhongphnlyLabel              matlab.ui.control.Label
        SlnlpEditFieldLabel             matlab.ui.control.Label
        Tab_1_SoLanLap                  matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel         matlab.ui.control.Label
        Tab_1_KetQua                    matlab.ui.control.NumericEditField
        Tab_1_Tinh                      matlab.ui.control.Button
        NhpsaischophpLabel              matlab.ui.control.Label
        Tab_1_SaiSo                     matlab.ui.control.NumericEditField
        Tab_1_UIAxes                    matlab.ui.control.UIAxes
        NisuyTab                        matlab.ui.container.Tab
        TinhButton_2                    matlab.ui.control.Button
        NhpdliuxEditFieldLabel          matlab.ui.control.Label
        NhpdliuxEditField               matlab.ui.control.EditField
        NhpgitrcnnisuyEditFieldLabel    matlab.ui.control.Label
        NhpgitrcnnisuyEditField         matlab.ui.control.NumericEditField
        NhnxtTextAreaLabel              matlab.ui.control.Label
        NhnxtTextArea                   matlab.ui.control.TextArea
        NhpdliuyEditFieldLabel          matlab.ui.control.Label
        NhpdliuyEditField               matlab.ui.control.EditField
        PhngphpDropDownLabel            matlab.ui.control.Label
        PhngphpDropDown                 matlab.ui.control.DropDown
        KtquathcnisuyEditFieldLabel     matlab.ui.control.Label
        KtquathcnisuyEditField          matlab.ui.control.EditField
        KtqunisuyEditFieldLabel         matlab.ui.control.Label
        KtqunisuyEditField              matlab.ui.control.NumericEditField
        UIAxes_2                        matlab.ui.control.UIAxes
        HiquyTab                        matlab.ui.container.Tab
        NhpdliuXEditFieldLabel          matlab.ui.control.Label
        Tab3_NhpdliuXEditField          matlab.ui.control.EditField
        Tab3_NhpdliuYEditField          matlab.ui.control.EditField
        NhpgitrcndonEditFieldLabel      matlab.ui.control.Label
        Tab3_NhpgitrcndonEditField      matlab.ui.control.NumericEditField
        NhpdliuXEditFieldLabel_2        matlab.ui.control.Label
        KtqudonEditFieldLabel           matlab.ui.control.Label
        Tab3_KtqudonEditField           matlab.ui.control.NumericEditField
        PhngPhpDropDownLabel            matlab.ui.control.Label
        Tab_3PhngPhpDropDown            matlab.ui.control.DropDown
        AEditFieldLabel                 matlab.ui.control.Label
        Tab3_AEditField                 matlab.ui.control.NumericEditField
        BEditFieldLabel                 matlab.ui.control.Label
        Tab3_BEditField                 matlab.ui.control.NumericEditField
        TnhButton                       matlab.ui.control.Button
        KtquphngtrnhhiquyEditFieldLabel  matlab.ui.control.Label
        Tab3_KtquphngtrnhhiquyEditField  matlab.ui.control.EditField
        Tab3_UIAxes                     matlab.ui.control.UIAxes
        ohmTab                          matlab.ui.container.Tab
        HoacLabel                       matlab.ui.control.Label
        TinhdaohamButton                matlab.ui.control.Button
        NhpdliuxEditField_3Label        matlab.ui.control.Label
        NhapdulieuxEditField_2          matlab.ui.control.EditField
        NhpdliuyEditField_3Label        matlab.ui.control.Label
        NhapdulieuyEditField_2          matlab.ui.control.EditField
        NhpbchEditFieldLabel            matlab.ui.control.Label
        NhapbuochEditField              matlab.ui.control.NumericEditField
        ChnphngphpohmDropDownLabel      matlab.ui.control.Label
        ChonphuongphapdaohamDropDown    matlab.ui.control.DropDown
        ChngitrsaisDropDownLabel        matlab.ui.control.Label
        ChongiatrisaisoDropDown         matlab.ui.control.DropDown
        NhpgitrcntnhohmEditFieldLabel   matlab.ui.control.Label
        NhapgiatricantinhdaohamEditField  matlab.ui.control.NumericEditField
        KtquEditFieldLabel              matlab.ui.control.Label
        KetquaEditField_2               matlab.ui.control.NumericEditField
        NhpHmsEditFieldLabel            matlab.ui.control.Label
        NhaphamsoEditField_2            matlab.ui.control.EditField
        NhnxtTextAreaLabel_3            matlab.ui.control.Label
        NhanxetTextArea_2               matlab.ui.control.TextArea
        TchphnTab                       matlab.ui.container.Tab
        NhapdulieuxEditFieldLabel       matlab.ui.control.Label
        NhapdulieuxEditField            matlab.ui.control.EditField
        NhapdulieuyEditFieldLabel       matlab.ui.control.Label
        NhapdulieuyEditField            matlab.ui.control.EditField
        HocLabel                        matlab.ui.control.Label
        NhaphamsoEditFieldLabel         matlab.ui.control.Label
        NhaphamsoEditField              matlab.ui.control.EditField
        PhuongphapDropDownLabel         matlab.ui.control.Label
        PhuongphapDropDown              matlab.ui.control.DropDown
        NhapcandetinhtichphanEditFieldLabel  matlab.ui.control.Label
        NhapcandetinhtichphanEditField  matlab.ui.control.EditField
        Button                          matlab.ui.control.Button
        NhanxetTextAreaLabel            matlab.ui.control.Label
        NhanxetTextArea                 matlab.ui.control.TextArea
        NhapNEditFieldLabel             matlab.ui.control.Label
        NhapNEditField                  matlab.ui.control.NumericEditField
        KetquaEditFieldLabel            matlab.ui.control.Label
        KetquaEditField                 matlab.ui.control.NumericEditField
        GiithiuNhmTab                   matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PhngphpDropDown
        function PhngphpDropDownValueChanged(app, event)
%Đoạn code nhắc nhở người dùng về việc chỉ dùng PP nội suy Newton cho dữ liệu X cách đều nhau                       
            if app.PhngphpDropDown.Value == "Newton"
                app.NhnxtTextArea.Value = "Phương pháp này dùng cho dữ liệu x có các giá trị cách đều nhau.";
            end
        end

        % Button pushed function: TinhButton_2
        function TinhButton_2Pushed(app, event)
            %================== Nội suy ==================%
%Load data in từ giao diện
            X = str2num(app.NhpdliuxEditField.Value);
            while size(X) == [0, 0] %nhập vào X là ký tự không phải chữ số
                app.NhnxtTextArea.Value = "Nhập lại dữ liệu x.";
                return
            end
            Y = str2num(app.NhpdliuyEditField.Value);
            while size(Y) == [0, 0] %nhập vào Y là ký tự không phải chữ số
                app.NhnxtTextArea.Value = "Nhập lại dữ liệu y.";
                return
            end
            while size(Y) ~= size(X) %kích thước của X và Y không bằng nhau -> không thể tính toán
                app.NhnxtTextArea.Value = "Nhập lại dữ liệu X hoặc Y do không trùng kích thước.";
                return
            end
            PP = app.PhngphpDropDown.Value;
            x_ = app.NhpgitrcnnisuyEditField.Value;
%==================================================================================================%            
            if PP == "Lagrange"
                u = sym('x');
                n = length(X);
                v = zeros(size(u));
                for i = 1:n
                    w = ones(size(u));
                    for j = [1:i-1 i+1:n]
                        w = (u - X(j)) ./ (X(i)-X(j)) .* w;
                    end
                    v = v + w  * Y(i);
                end %kết thúc đoạn code tìm đa thức nội suy bằng PP Lagrange
            else %PP là Newton  
                for i = 2:size(X,2)-1 %kiểm tra X có các giá trị cách đều nhau hay không
                    h1 = X(i) - X(i-1);
                    h2 = X(i+1) - X(i);
                    if h1 ~= h2
                        app.NhnxtTextArea.Value = "Dữ liệu X không cách đều nhau. " + ...
                        "Không dùng phương pháp Newton để tìm đa thức nội suy.";
                        return
                    end
                end
                u = sym('x');
                n = length(Y);
                d = Y(:);
                for j = 2:n
                    for i = n:-1:j
                        d(i) = (d(i)-d(i-1)) / (X(i)- X(i-j+1));
                    end
                end
                v = d(n) * ones(size(u));
                for i = n-1:-1:1
                    v = v .* (u-X(i)) + d(i);
                end %kết thúc đoạn code tìm đa thức nội suy bằng PP Newton
            end
%==================================================================================================%             
            v = matlabFunction(v); %In ra giao diện kết quả và vẽ đồ thị
            t = X(1)-5:0.01:X(size(X,2))+5;
            plot(app.UIAxes_2, X, Y,'o', t, v(t));
            legend(app.UIAxes_2, 'Dữ liệu X và Y','Đa thức nội suy từ dữ liệu x và y.');
            app.KtqunisuyEditField.Value = v(x_);
            app.KtquathcnisuyEditField.Value = func2str(v);
            app.NhnxtTextArea.Value = "Không có nhận xét.";
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %Khai báo các block nhập dữ liệu từ giao diện
            f = app.NhaphamsoEditField.Value;
            n = app.NhapNEditField.Value;
            ab = str2num(app.NhapcandetinhtichphanEditField.Value);
            a = ab(1,1);
            b = ab(1,2);
            %Cac dieu kien cua giao dien
            if f == "None"
                X = str2num(app.NhapdulieuxEditField.Value);
                Y = str2num(app.NhapdulieuyEditField.Value);
                %Xét điều kiện nhập vào của x y
                while size(X,1) == 0 %nhập vào X là ký tự không phải chữ số
                    app.NhanxetTextArea.Value = "Nhập lại dữ liệu x";
                    return
                end
                while size(Y,1) == 0 %nhập vào Y là ký tự không phải chữ số
                    app.NhanxetTextArea.Value = "Nhập lại dữ liệu y";
                    return
                end
                while size(Y,2) ~= size(X,2)
                    app.NhanxetTextArea.Value = "Nhập lại dữ liệu x và y do không cùng kích thước";
                    return
                end
                %Nội suy Lagrange để tìm đa thức từ giá trị x và y
                u = sym('x');
                l = length(X);
                v = zeros(size(u));
                for i = 1:l
                    w = ones(size(u));
                    for j = [1:i-1 i+1:l]
                        w = (u - X(j)) ./ (X(i)-X(j)) .* w;
                    end
                    v = v + w  * Y(i);
                end
                fx = matlabFunction(v);
            else
                fx = str2func(['@(x)', f]);
            end 
            %Tinh tich phan = cac phuong phap sau
            pp = app.PhuongphapDropDown.Value;
            if pp == "Hinh thang"
                h = (b - a) / n;
                I = h/2 * (fx(a) + fx(b) + 2 * sum(fx(a+h:h:b-h)));              
            end 
            if pp == "Simpson 1/3"
                h = (b - a) / n;
                I = h/3 * (fx(a) + fx(b) + 4*sum(fx(a+h:2*h:b-h)) + 2*sum(fx(a+2*h:2*h:b-2*h)));
            end
            if pp == "Simpson 3/8"
                if mod(n, 3) == 1
                    n = n + 2;
                end
                if mod(n, 3) == 2
                    n = n + 1;
                end
                h = (b - a) / n;
                I = (3*h)/8 * (fx(a) + fx(b) + 3*sum(fx(a+h:h:b-h)) - sum(fx(a+3*h:3*h:b-2*h)));
            end    
            %Chạy
            app.KetquaEditField.Value = I;
        end

        % Button pushed function: Tab_1_Tinh
        function Tab_1_TinhButtonPushed(app, event)
             % Khai báo biến
            nghiem = 0;
            solanlap = 0;
            isLap = false;
            
            % Kiểm tra phương trình nhập
            fx = app.Tab_1_HamSo.Value;
            if isempty(fx)
                msgbox("Chưa nhập phương trình!");
            end
            
            % Kiểm tra khoảng phân ly
            a = app.Tab_1_a.Value;
            b = app.Tab_1_b.Value;
            if isempty(a) && isempty(b)
                msgbox("Chưa nhập khoảng phân ly!");
            elseif (a >= b)
                msgbox("Cận a phải nhỏ hơn cận b");
            end
            
            % Kiểm tra sai số
            saiso = app.Tab_1_SaiSo.Value;
            if saiso < 0
                msgbox("Sai số cho phép phải là số dương");
            end
            
            % Kiểm tra phương pháp tìm nghiệm do người dùng chọn
            if app.Tab_1_PP.Value == "Chia đôi"
                [nghiem, solanlap] = ChiaDoi(fx, a, b, saiso);
            elseif app.Tab_1_PP.Value == "Newton (Tuyến tính)"
                [nghiem, solanlap] = Newton_Raphson(fx, a, b, saiso);
            elseif app.Tab_1_PP.Value == "Lặp"
                gx = inputdlg("Nhập hàm lặp: ","Nhập phương trình lặp",[1,50],{'','NULL'});
                if ~isempty(gx) && ~isempty(gx{1})
                    [nghiem, solanlap] = LapDon(fx, gx{1}, a, b, saiso);
                    isLap = true;
                else
                    msgbox("Chưa nhập hàm lặp!");
                end
            end
            
            % Hiển thị kết quả và số lần lặp
            app.Tab_1_KetQua.Value = nghiem;
            app.Tab_1_SoLanLap.Value = solanlap;
            
            % Vẽ đồ thị
            n = linspace(a, b); % Khoảng nghiệm phân ly
            
            if isLap
                % Vẽ hàm số nếu là phương pháp lặp
                f = @(x) x; % Hàm y = x
                g = str2func(['@(x)' gx{1}]);
                fx = f(n);
                gx = g(n);
                plot(app.Tab_1_UIAxes, n, gx, 'r', n, fx,'b', nghiem,g(nghiem),'ko');
                legend(app.Tab_1_UIAxes,"Hàm số g(x)","Hàm số f(x)","Nghiệm gần đúng",'Location', 'southeast', 'Orientation', 'horizontal');
            end
            
             % Vẽ hàm số nếu là phương pháp chia đôi hoặc tuyến tính
             fx = str2func(['@(x)', fx]);
             y = fx(n);
             plot(app.Tab_1_UIAxes,n, y,'b', nghiem,0,'ko');  %Vẽ hàm số theo trục x và nghiệm gần đúng
             legend(app.Tab_1_UIAxes,"Hàm số","Nghiệm gần đúng",'Location', 'southeast', 'Orientation', 'horizontal');
        end

        % Button pushed function: TnhButton
        function Tab_3_TinhButtonPushed(app, event)
                        %================== Hồi Quy ==================%

                a = 0;
                b = 0;
                c = 0;
                
                X = str2num(app.Tab3_NhpdliuXEditField.Value);
                while isempty(X) 
                    msgbox("Vui lòng nhập kí tự X không phải chữ số!");
                    return
                end
                
                Y = str2num(app.Tab3_NhpdliuYEditField.Value);
                while isempty(Y) 
                    msgbox("Vui lòng nhập kí tự Y không phải chữ số!");
                    return
                end
                
                if length(Y) ~= length(X)
                    msgbox("Vui lòng nhập kí tự X và Y cùng kích thước!");
                    return
                end
                
                z = app.Tab3_NhpgitrcndonEditField.Value;
                PP = app.Tab_3PhngPhpDropDown.Value;
                
                if PP == "Tuyến Tính"
                    n = length(X);
                    sumx = 0;
                    sumy = 0;
                    sumxy = 0;
                    sumx2 = 0;
                    for i = 1:n
                        sumx = sumx + X(i);
                        sumy = sumy + Y(i);
                        sumxy = sumxy + X(i)*Y(i);
                        sumx2 = sumx2 + X(i)*X(i);
                    end 
                    xm = sumx/n;
                    ym = sumy/n;
                    a = (n*sumxy - sumx*sumy)/(n*sumx2 - sumx*sumx);
                    b = ym - a*xm;
                    app.Tab3_AEditField.Value = a;
                    app.Tab3_BEditField.Value = b;
                elseif PP == "Hàm Mũ"
                    x = log10(X);
                    y = log10(Y);
                    n = length(x);
                    sumx = 0;
                    sumy = 0;
                    sumxy = 0;
                    sumx2 = 0;
                    for i = 1:n
                        sumx = sumx + x(i);
                        sumy = sumy + y(i);
                        sumxy = sumxy + x(i)*y(i);
                        sumx2 = sumx2 + x(i)*x(i);
                    end 
                    xm = sumx/n;
                    ym = sumy/n;
                    a = (n*sumxy - sumx*sumy)/(n*sumx2 - sumx*sumx);
                    b = ym - a*xm;
                
                    A = 10^b;
                    B = a;
                
                    app.Tab3_AEditField.Value = a;
                    app.Tab3_BEditField.Value = b;
                elseif PP == "Logarit"
                    if length(X) ~= length(Y)
                        msgbox("Vui lòng nhập kí tự X và Y cùng kích thước!");
                        return
                    end
                    
                    x = log(X);
                    y = log(Y);
                    n = length(x);
                    sumx = sum(x);
                    sumy = sum(y);
                    sumxy = sum(x.*y);
                    sumx2 = sum(x.*x);
                    xm = sumx/n;
                    ym = sumy/n;
                    a = (n*sumxy - sumx*sumy)/(n*sumx2 - sumx*sumx);
                    b = ym - a * xm;
                
                    m = exp(a * log(X) + b);
                    C = exp(a * log(z) + b);
                    app.Tab3_AEditField.Value = a;
                    app.Tab3_BEditField.Value = b;
                    app.Tab3_KtqudonEditField.Value = C;
                end
                
                if a < 0
                    a_str = sprintf('(%.2f).x', a);
                else
                    a_str = sprintf('%.2f.x', a);
                end
                
                if b < 0
                    b_str = sprintf('(%.2f)', b);
                else
                    b_str = sprintf('%.2f', b);
                end
                
                if PP == "Logarit"
                    app.Tab3_KtquphngtrnhhiquyEditField.Value = strcat(a_str, ' log ',b_str);
                elseif PP == "Hàm Mũ"
                    app.Tab3_KtquphngtrnhhiquyEditField.Value = strcat(a_str, ' ^ ', b_str);
                else
                    app.Tab3_KtquphngtrnhhiquyEditField.Value = strcat(a_str, ' + ', b_str);
                end                
                % Đồ Thị
                n = linspace(min(X), max(X));
                if PP == "Tuyến Tính"
                    m = a.*n + b;
                    C = a.*z + b;
                    app.Tab3_KtqudonEditField.Value = C;
                elseif PP == "Hàm Mũ"
                    m = A.*n.^B;
                    C = A.*z.^B;
                    app.Tab3_KtqudonEditField.Value = C;
                elseif PP == "Logarit"
                    m = exp(a * log(n) + b);
                    C = exp(a * log(z) + b);
                    app.Tab3_KtqudonEditField.Value = C;
                end
                
                % Plot
                plot(app.Tab3_UIAxes, X, Y, 'o', n, m, z, C, "*");
                xlabel(app.Tab3_UIAxes, 'X');
                ylabel(app.Tab3_UIAxes, 'Y');
                legend(app.Tab3_UIAxes, 'Data', 'Regression','Nghiệm');
        end

        % Button pushed function: TinhdaohamButton
        function TinhdaohamButtonPushed(app, event)
            
            f = app.NhaphamsoEditField_2.Value;
            x = app.NhapgiatricantinhdaohamEditField.Value;
            h = app.NhapbuochEditField.Value;

            
           %Cac dieu kien cua giao dien

            if f == "None"
                X = str2num(app.NhapdulieuxEditField_2.Value);
                Y = str2num(app.NhapdulieuyEditField_2.Value);
                %Xét điều kiện nhập vào của x y
                while size(X,1) == 0 %nhập vào X là ký tự không phải chữ số
                    app.NhanxetTextArea_2.Value = "Nhập lại dữ liệu x";
                    return
                end
                while size(Y,1) == 0 %nhập vào Y là ký tự không phải chữ số
                    app.NhanxetTextArea_2.Value = "Nhập lại dữ liệu y";
                    return
                end
                while size(Y,2) ~= size(X,2)
                    app.NhanxetTextArea_2.Value = "Nhập lại dữ liệu x và y do không cùng kích thước";
                    return
                end
                %Nội suy Lagrange để tìm đa thức từ giá trị x và y
                u = sym('x');
                l = length(X);
                v = zeros(size(u));
                for i = 1:l
                    w = ones(size(u));
                    for j = [1:i-1 i+1:l]
                        w = (u - X(j)) ./ (X(i)-X(j)) .* w;
                    end
                    v = v + w  * Y(i);
                end
                fx = matlabFunction(v);
            else 
                
                fx = str2func(['@(x)', f]);
            end 
            
            saiso = app.ChongiatrisaisoDropDown.Value;
            pp = app.ChonphuongphapdaohamDropDown.Value;

            if strcmp(pp,'Xap xi tien')
                if strcmp(saiso,'O(h)')
                    dx = (-fx(x) + fx(x+h)) / (h);
                else 
                    dx = (-3*fx(x) + 4* fx(x+h) - fx(x+2*h)) / (2*h);
                end
            elseif strcmp(pp,'Xap xi lui')
                if strcmp(saiso,'O(h)')
                    dx = (-fx(x-h) + fx(x))/ h;
                else
                    dx = (fx(x - 2*h) -4*fx(x-h) + 3*fx(x)) / (2*h);
                end
            elseif strcmp(pp,'Xap xi trung tam')
                if strcmp(saiso,'O(h^2)')
                    dx = (-fx(x-h) + fx(x+h)) / (2*h);
                else 
                    dx = (-fx(x+2*h) + 8*fx(x+h) -8*fx(x-h) + fx(x - 2*h))/ (12*h);
                end
            end
            
            
            app.KetquaEditField_2.Value = dx;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.GridLayout = uigridlayout(app.NghimTab);
            app.Tab_1_PP = uidropdown(app.GridLayout);
            app.Tab_1_PP.Items = {'Chọn phương pháp tìm nghiệm', 'Chia đôi', 'Lặp', 'Newton (Tuyến tính)'};
            app.Tab_1_PP.Value = 'Chọn phương pháp tìm nghiệm';
            app.NhpphngtrnhLabel = uilabel(app.GridLayout);
            app.NhpphngtrnhLabel.Text = 'Nhập phương trình:';
            app.Tab_1_HamSo = uieditfield(app.GridLayout, 'text');
            app.CnbEditFieldLabel = uilabel(app.GridLayout);
            app.CnbEditFieldLabel.Text = 'Cận b';
            app.Tab_1_b = uieditfield(app.GridLayout, 'numeric');
            app.CnaEditFieldLabel = uilabel(app.GridLayout);
            app.CnaEditFieldLabel.Text = 'Cận a';
            app.Tab_1_a = uieditfield(app.GridLayout, 'numeric');
            app.NhpkhongphnlyLabel = uilabel(app.GridLayout);
            app.NhpkhongphnlyLabel.Text = 'Nhập khoảng phân ly:';
            app.SlnlpEditFieldLabel = uilabel(app.GridLayout);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.Tab_1_SoLanLap = uieditfield(app.GridLayout, 'numeric');
            app.KtqunghimEditFieldLabel = uilabel(app.GridLayout);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm';
            app.Tab_1_KetQua = uieditfield(app.GridLayout, 'numeric');
            app.Tab_1_Tinh = uibutton(app.GridLayout, 'push');
            app.Tab_1_Tinh.ButtonPushedFcn = createCallbackFcn(app, @Tab_1_TinhButtonPushed, true);
            app.Tab_1_Tinh.Text = 'Tính';
            app.NhpsaischophpLabel = uilabel(app.GridLayout);
            app.NhpsaischophpLabel.Text = 'Nhập sai số cho phép:';
            app.Tab_1_SaiSo = uieditfield(app.GridLayout, 'numeric');
            app.Tab_1_UIAxes = uiaxes(app.GridLayout);
            title(app.Tab_1_UIAxes, 'ĐỒ THỊ HÀM SỐ')
            xlabel(app.Tab_1_UIAxes, 'X')
            ylabel(app.Tab_1_UIAxes, 'Y')
            zlabel(app.Tab_1_UIAxes, 'Z')
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.TinhButton_2 = uibutton(app.NisuyTab, 'push');
            app.TinhButton_2.ButtonPushedFcn = createCallbackFcn(app, @TinhButton_2Pushed, true);
            app.TinhButton_2.Text = {'Tinh'; ''};
            app.NhpdliuxEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = ' Nhập giá trị cần nội suy';
            app.NhpgitrcnnisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.NhpgitrcnnisuyEditField.ValueDisplayFormat = '%5.4f';
            app.NhnxtTextAreaLabel = uilabel(app.NisuyTab);
            app.NhnxtTextAreaLabel.Text = 'Nhận xét';
            app.NhnxtTextArea = uitextarea(app.NisuyTab);
            app.NhnxtTextArea.Value = {'Không có nhận xét.'};
            app.NhpdliuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField = uieditfield(app.NisuyTab, 'text');
            app.PhngphpDropDownLabel = uilabel(app.NisuyTab);
            app.PhngphpDropDownLabel.Text = 'Phương pháp';
            app.PhngphpDropDown = uidropdown(app.NisuyTab);
            app.PhngphpDropDown.Items = {'Lagrange', 'Newton'};
            app.PhngphpDropDown.ValueChangedFcn = createCallbackFcn(app, @PhngphpDropDownValueChanged, true);
            app.PhngphpDropDown.Value = 'Lagrange';
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.KtquathcnisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.KtqunisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.KtqunisuyEditField.ValueDisplayFormat = '%5.4f';
            app.UIAxes_2 = uiaxes(app.NisuyTab);
            title(app.UIAxes_2, 'Đa thức nội suy')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.NhpdliuXEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpdliuXEditFieldLabel.Text = 'Nhập dữ liệu X';
            app.Tab3_NhpdliuXEditField = uieditfield(app.HiquyTab, 'text');
            app.Tab3_NhpdliuYEditField = uieditfield(app.HiquyTab, 'text');
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.Tab3_NhpgitrcndonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.NhpdliuXEditFieldLabel_2 = uilabel(app.HiquyTab);
            app.NhpdliuXEditFieldLabel_2.Text = 'Nhập dữ liệu Y';
            app.KtqudonEditFieldLabel = uilabel(app.HiquyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.Tab3_KtqudonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.PhngPhpDropDownLabel = uilabel(app.HiquyTab);
            app.PhngPhpDropDownLabel.Text = 'Phương Pháp';
            app.Tab_3PhngPhpDropDown = uidropdown(app.HiquyTab);
            app.Tab_3PhngPhpDropDown.Items = {'Tuyến Tính', 'Hàm Mũ', 'Logarit'};
            app.Tab_3PhngPhpDropDown.Value = 'Tuyến Tính';
            app.AEditFieldLabel = uilabel(app.HiquyTab);
            app.AEditFieldLabel.Text = 'A';
            app.Tab3_AEditField = uieditfield(app.HiquyTab, 'numeric');
            app.BEditFieldLabel = uilabel(app.HiquyTab);
            app.BEditFieldLabel.Text = 'B';
            app.Tab3_BEditField = uieditfield(app.HiquyTab, 'numeric');
            app.TnhButton = uibutton(app.HiquyTab, 'push');
            app.TnhButton.ButtonPushedFcn = createCallbackFcn(app, @Tab_3_TinhButtonPushed, true);
            app.TnhButton.Text = 'Tính';
            app.KtquphngtrnhhiquyEditFieldLabel = uilabel(app.HiquyTab);
            app.KtquphngtrnhhiquyEditFieldLabel.Text = 'Kết quả phương trình hồi quy';
            app.Tab3_KtquphngtrnhhiquyEditField = uieditfield(app.HiquyTab, 'text');
            app.Tab3_UIAxes = uiaxes(app.HiquyTab);
            title(app.Tab3_UIAxes, 'Đồ Thị Hàm Hồi Quy')
            xlabel(app.Tab3_UIAxes, 'X')
            ylabel(app.Tab3_UIAxes, 'Y')
            zlabel(app.Tab3_UIAxes, 'Z')
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.HoacLabel = uilabel(app.ohmTab);
            app.HoacLabel.Text = 'Hoặc';
            app.TinhdaohamButton = uibutton(app.ohmTab, 'push');
            app.TinhdaohamButton.ButtonPushedFcn = createCallbackFcn(app, @TinhdaohamButtonPushed, true);
            app.TinhdaohamButton.Text = 'Tính đạo hàm';
            app.NhpdliuxEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuxEditField_3Label.Text = 'Nhâp dữ liệu x';
            app.NhapdulieuxEditField_2 = uieditfield(app.ohmTab, 'text');
            app.NhapdulieuxEditField_2.Value = '[0.1 0.3 0.5 0.7 0.9]';
            app.NhpdliuyEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuyEditField_3Label.Text = 'Nhập dữ liệu y';
            app.NhapdulieuyEditField_2 = uieditfield(app.ohmTab, 'text');
            app.NhapdulieuyEditField_2.Value = '[0.1002 0.3047 0.5236 0.7754 1.1198]';
            app.NhpbchEditFieldLabel = uilabel(app.ohmTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.NhapbuochEditField = uieditfield(app.ohmTab, 'numeric');
            app.ChnphngphpohmDropDownLabel = uilabel(app.ohmTab);
            app.ChnphngphpohmDropDownLabel.Text = 'Chọn phương pháp đạo hàm';
            app.ChonphuongphapdaohamDropDown = uidropdown(app.ohmTab);
            app.ChonphuongphapdaohamDropDown.Items = {'Xap xi tien', 'Xap xi lui', 'Xap xi trung tam'};
            app.ChonphuongphapdaohamDropDown.Value = 'Xap xi tien';
            app.ChngitrsaisDropDownLabel = uilabel(app.ohmTab);
            app.ChngitrsaisDropDownLabel.Text = 'Chọn giá trị sai số';
            app.ChongiatrisaisoDropDown = uidropdown(app.ohmTab);
            app.ChongiatrisaisoDropDown.Items = {'O(h)', 'O(h^2)', 'O(h^4)'};
            app.ChongiatrisaisoDropDown.Value = 'O(h)';
            app.NhpgitrcntnhohmEditFieldLabel = uilabel(app.ohmTab);
            app.NhpgitrcntnhohmEditFieldLabel.Text = 'Nhâp giá trị cần tính đạo hàm';
            app.NhapgiatricantinhdaohamEditField = uieditfield(app.ohmTab, 'numeric');
            app.KtquEditFieldLabel = uilabel(app.ohmTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.KetquaEditField_2 = uieditfield(app.ohmTab, 'numeric');
            app.NhpHmsEditFieldLabel = uilabel(app.ohmTab);
            app.NhpHmsEditFieldLabel.Text = 'Nhập Hàm số';
            app.NhaphamsoEditField_2 = uieditfield(app.ohmTab, 'text');
            app.NhaphamsoEditField_2.Value = 'None';
            app.NhnxtTextAreaLabel_3 = uilabel(app.ohmTab);
            app.NhnxtTextAreaLabel_3.Text = 'Nhận xét';
            app.NhanxetTextArea_2 = uitextarea(app.ohmTab);
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.NhapdulieuxEditFieldLabel = uilabel(app.TchphnTab);
            app.NhapdulieuxEditFieldLabel.Text = 'Nhap du lieu x';
            app.NhapdulieuxEditField = uieditfield(app.TchphnTab, 'text');
            app.NhapdulieuyEditFieldLabel = uilabel(app.TchphnTab);
            app.NhapdulieuyEditFieldLabel.Text = 'Nhap du lieu y';
            app.NhapdulieuyEditField = uieditfield(app.TchphnTab, 'text');
            app.HocLabel = uilabel(app.TchphnTab);
            app.HocLabel.Text = 'Hoặc';
            app.NhaphamsoEditFieldLabel = uilabel(app.TchphnTab);
            app.NhaphamsoEditFieldLabel.Text = 'Nhap ham so';
            app.NhaphamsoEditField = uieditfield(app.TchphnTab, 'text');
            app.NhaphamsoEditField.Value = 'x.^3 .*sin(x)';
            app.PhuongphapDropDownLabel = uilabel(app.TchphnTab);
            app.PhuongphapDropDownLabel.Text = 'Phuong phap';
            app.PhuongphapDropDown = uidropdown(app.TchphnTab);
            app.PhuongphapDropDown.Items = {'Hinh thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.PhuongphapDropDown.Value = 'Hinh thang';
            app.NhapcandetinhtichphanEditFieldLabel = uilabel(app.TchphnTab);
            app.NhapcandetinhtichphanEditFieldLabel.Text = 'Nhap can de tinh tich phan';
            app.NhapcandetinhtichphanEditField = uieditfield(app.TchphnTab, 'text');
            app.NhapcandetinhtichphanEditField.Value = '[0 1]';
            app.Button = uibutton(app.TchphnTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.NhanxetTextAreaLabel = uilabel(app.TchphnTab);
            app.NhanxetTextAreaLabel.Text = 'Nhan xet';
            app.NhanxetTextArea = uitextarea(app.TchphnTab);
            app.NhapNEditFieldLabel = uilabel(app.TchphnTab);
            app.NhapNEditFieldLabel.Text = 'Nhap N';
            app.NhapNEditField = uieditfield(app.TchphnTab, 'numeric');
            app.NhapNEditField.Value = 10;
            app.KetquaEditFieldLabel = uilabel(app.TchphnTab);
            app.KetquaEditFieldLabel.Text = 'Ket qua';
            app.KetquaEditField = uieditfield(app.TchphnTab, 'numeric');
            app.GiithiuNhmTab = uitab(app.TabGroup);
            app.GiithiuNhmTab.Title = 'Giới thiệu Nhóm';
            app.TextArea = uitextarea(app.GiithiuNhmTab);
            app.TextArea.Value = {'21207217_Nguyễn Phan Thiên Tân_Tab Tích Phân'; ''; '21207218_Nguyễn Thanh Tân_Tab Nội suy'; ''; '21207231_Cao Duy Toàn_Tab Nghiệm'; ''; '21207238_Nguyễn Vũ Tuấn_Word'; ''; '21207240_Nguyễn Thanh Tùng_Tab Đạo hàm'; ''; '21207244_Trần Viết Lân_Tab Hồi quy'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app11

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end